classdef HypothesisTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RunButton                       matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        DistributionDropDown            matlab.ui.control.DropDown
        DistributionDropDownLabel       matlab.ui.control.Label
        TailDropDown                    matlab.ui.control.DropDown
        TailDropDownLabel               matlab.ui.control.Label
        SignificancelevelEditField      matlab.ui.control.NumericEditField
        SignificancelevelEditFieldLabel  matlab.ui.control.Label
        TeststatisticEditField          matlab.ui.control.NumericEditField
        TeststatisticEditFieldLabel     matlab.ui.control.Label
        NumeratordfEditField            matlab.ui.control.NumericEditField
        NumeratordfEditFieldLabel       matlab.ui.control.Label
        DegreesoffreedomEditField       matlab.ui.control.NumericEditField
        DegreesoffreedomEditFieldLabel  matlab.ui.control.Label
        DenominatordfEditField          matlab.ui.control.NumericEditField
        DenominatordfEditFieldLabel     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mp = get(0, 'MonitorPositions');
            mwidth = mp(1, 3);
            mheight = mp(1, 4);
            scale = 0.7;

            gwidth = scale*mwidth;
            gheight = scale*mheight;
            x0 = 0.5*mwidth*(1 - scale);
            y0 = 0.5*mheight*(1 - scale);
            
            app.UIFigure.Position = [x0, y0, gwidth, gheight];
        end

        % Value changed function: DistributionDropDown
        function DistributionDropDownValueChanged(app, event)
            cla(app.UIAxes, "reset");
            
            distribution = app.DistributionDropDown.Value;
            % The F-distribution has two 'degrees of freedom. The code here
            % changes the UI so that the user can fill in values for both
            % degrees of freedom in case that they want to perform an
            % F-test.
            if distribution == "F"
                app.DegreesoffreedomEditField.Visible = "off";
                app.DegreesoffreedomEditFieldLabel.Visible = "off";
                app.NumeratordfEditField.Visible = "on";
                app.NumeratordfEditFieldLabel.Visible = "on";
                app.DenominatordfEditField.Visible = "on";
                app.DenominatordfEditFieldLabel.Visible = "on";
            else
                app.DegreesoffreedomEditField.Visible = "on";
                app.DegreesoffreedomEditFieldLabel.Visible = "on";
                app.NumeratordfEditField.Visible = "off";
                app.NumeratordfEditFieldLabel.Visible = "off";
                app.DenominatordfEditField.Visible = "off";
                app.DenominatordfEditFieldLabel.Visible = "off";
            end

            % This section updates the title and x-axis label, which
            % depends on the chosen distribution.
            switch(distribution)
                case "Student's t"
                    app.UIAxes.Title.String = "t-distribution";
                    app.UIAxes.XLabel.String = "t-value";
                    app.UIAxes.XTick = [-5 0 5];
                    app.UIAxes.XTickLabel = [-5 0 5];
                    app.UIAxes.XLim = [-5 5];
                case "F"
                    app.UIAxes.Title.String = "F-distribution";
                    app.UIAxes.XLabel.String = "F-value";
                    app.UIAxes.XTick = 9;
                    app.UIAxes.XTickLabel = 9;
                    app.UIAxes.XLim = [0 9];
                case "Chi-squared"
                    app.UIAxes.Title.String = "Chi-squared distribution";
                    app.UIAxes.XLabel.String = "Chi-squared value";
                    app.UIAxes.XTick = 9;
                    app.UIAxes.XTickLabel = 9;
                    app.UIAxes.XLim = [0 9];
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            % Check whether the user wants to plot the test statistic.
            plottstat = app.CheckBox.Value;
            if plottstat == 1
                app.TeststatisticEditField.Visible = "on";
            else
                app.TeststatisticEditField.Visible = "off";
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            cla(app.UIAxes, "reset");

            distribution = app.DistributionDropDown.Value;
            side = app.TailDropDown.Value;
            alpha = app.SignificancelevelEditField.Value;

            app.UIAxes.YLabel.String = "Density";

            % Creating the decimal settings.
            alphadec = sprintf('%%.%df', ...
                length(char(extractAfter(string(alpha),'.'))));
            nodec = sprintf('%%.%df', 0);
            if distribution == "F"
                nu1 = app.NumeratordfEditField.Value;
                nu2 = app.DenominatordfEditField.Value;
                variables = sprintf(['\\alpha = ', alphadec, ...
                    ', \\nu_{1} = ', nodec, ', \\nu_{2} = ' nodec], ...
                    alpha, nu1, nu2);
            else
                nu = app.DegreesoffreedomEditField.Value;
                variables = sprintf(['\\alpha = ', alphadec, ...
                    ', \\nu = ', nodec], alpha, nu);
            end

            % All calculations that depend on the specific distribution
            % (and side).
            if distribution == "Student's t"
                pd = 'T';
                app.UIAxes.Title.String = "t-distribution";
                app.UIAxes.XLabel.String = "t-value";
                switch(side)
                    case "Right"
                        CV = icdf(pd, 1-alpha, nu);
                        xmin = min(-CV, max(-10, icdf(pd, 0.0001, nu)));
                        xmax = max(CV, min(10, icdf(pd, 0.9999, nu)));

                        xcv = CV:0.001:xmax;
                        ycv = pdf(pd, xcv, nu);

                        xticks = [min(0, CV) max(0, CV)];

                    case "Left"
                        CV = icdf(pd, alpha, nu);
                        xmin = min(CV, max(-10, icdf(pd, 0.0001, nu)));
                        xmax = max(-CV, min(10, icdf(pd, 0.9999, nu)));

                        xcv = xmin:0.001:CV;
                        ycv = pdf(pd, xcv, nu);

                        xticks = [min(CV, 0) max(CV, 0)];
                    case "Both"
                        CVright = icdf(pd, 1-alpha/2, nu);
                        CVleft = -CVright;
                        CV = [CVleft CVright];

                        xmin = min(CVleft, max(-10, icdf(pd, ...
                            0.0001, nu)));
                        xmax = max(CVright, min(10, icdf(pd, ...
                            0.9999, nu)));

                        xcvleft = xmin:0.001:CVleft;
                        xcvright = CVright:0.001:xmax;
                        ycvleft = pdf(pd, xcvleft, nu);
                        ycvright = pdf(pd, xcvright, nu);

                        xticks = [CVleft 0 CVright];
                end

            elseif distribution == "F"
                pd = 'F';
                app.UIAxes.Title.String = "F-distribution";
                app.UIAxes.XLabel.String = "F-value";

                xmin = 0;
                xmax = icdf(pd, 0.9999, nu1, nu2);
                switch(side)
                    case "Right"
                        CV = icdf(pd, 1-alpha, nu1, nu2);

                        xcv = CV:0.001:xmax;
                        ycv = pdf(pd, xcv, nu1, nu2);

                        xticks = CV;
                    case "Left"
                        CV = icdf(pd, alpha, nu1, nu2);

                        xcv = xmin:0.001:CV;
                        ycv = pdf(pd, xcv, nu1, nu2);

                        xticks = CV;
                    case "Both"
                        CVleft = icdf(pd, alpha/2, nu1, nu2);
                        CVright = icdf(pd, 1-alpha/2, nu1, nu2);
                        CV = [CVleft CVright];

                        xcvleft = xmin:0.001:CVleft;
                        xcvright = CVright:0.001:xmax;
                        ycvleft = pdf('F', xcvleft, nu1, nu2);
                        ycvright = pdf('F', xcvright, nu1, nu2);

                        xticks = [CVleft CVright];
                end
            elseif distribution == "Chi-squared"
                pd = 'Chisquare';
                app.UIAxes.Title.String = "Chi-squared distribution";
                app.UIAxes.XLabel.String = "Chi-squared value";

                xmin = 0;
                xmax = icdf(pd, 0.9999, nu);
                switch(side)
                    case "Right"
                        CV = icdf(pd, 1-alpha, nu);

                        xcv = CV:0.001:xmax;
                        ycv = pdf(pd, xcv, nu);

                        xticks = CV;
                    case "Left"
                        CV = icdf(pd, alpha, nu);

                        xcv = xmin:0.001:CV;
                        ycv = pdf('Chisquare', xcv, nu);

                        xticks = CV;
                    case "Both"
                        CVleft = icdf(pd, alpha/2, nu);
                        CVright = icdf(pd, 1-alpha/2, nu);
                        CV = [CVleft CVright];

                        xcvleft = xmin:0.001:CVleft;
                        xcvright = CVright:0.001:xmax;
                        ycvleft = pdf('Chisquare', xcvleft, nu);
                        ycvright = pdf('Chisquare', xcvright, nu);

                        xticks = [CVleft CVright];
                end
            end
            
            app.UIAxes.XLim = [xmin xmax];
            app.UIAxes.XTick = xticks;
            app.UIAxes.XTickLabel = xticks;

            x = xmin:0.01:xmax;

            if distribution == 'F'
                y = pdf(pd, x, nu1, nu2);
            else
                y = pdf(pd, x, nu);
            end

            hold(app.UIAxes, "on")
            plot(app.UIAxes, x, y, '-black')
            xline(app.UIAxes, CV, 'LineStyle', ':', 'Color', '#9a9afc', ...
                'LineWidth', 1.4);

            switch(side)
                case "Both"
                    arleft = area(app.UIAxes, xcvleft, ycvleft);
                    arleft.FaceColor = 'blue';
                    arleft.FaceAlpha = 0.15;
                    arleft.EdgeColor = 'none';

                    arright = area(app.UIAxes, xcvright, ycvright);
                    arright.FaceColor = 'blue';
                    arright.FaceAlpha = 0.15;
                    arright.EdgeColor = 'none';
                otherwise
                    ar = area(app.UIAxes, xcv, ycv);
                    ar.FaceColor = 'blue';
                    ar.FaceAlpha = 0.15;
                    ar.EdgeColor = 'none';
            end

            % Plotting the test statistic and calculating the p value.
            plottstat = app.CheckBox.Value;
            tstat = app.TeststatisticEditField.Value;

            if plottstat == 1
                switch(side)
                    case "Right"
                        if tstat < CV % Unable to reject.
                            xline(app.UIAxes, tstat, 'LineStyle', ...
                                ':', 'Color', '#ae9ab5', ...
                                'LineWidth', 1.4);
                            tint = tstat:0.001:CV;

                            if distribution == 'F'
                                ty = pdf(pd, tint, nu1, nu2);
                            else
                                ty = pdf(pd, tint, nu);
                            end

                            tar = area(app.UIAxes, tint, ty);
                            tar.FaceAlpha = 0.04;
                        else % Able to reject.
                            xline(app.UIAxes, tstat, 'LineStyle', ...
                                ':', 'Color', '#8a22b3', ...
                                'LineWidth', 1.4);
                            if (tstat < xmax)
                                tint = tstat:0.001:xmax;

                                if distribution == 'F'
                                    ty = pdf(pd, tint, nu1, nu2);
                                else
                                    ty = pdf(pd, tint, nu);
                                end

                                tar = area(app.UIAxes, tint, ty);
                                tar.FaceAlpha = 1;
                            end
                        end
                        tar.FaceColor = '#8a22b3';
                        tar.EdgeColor = 'none';

                        if distribution == 'F'
                            pval = 1-cdf(pd, tstat, nu1, nu2);
                        else
                            pval = 1-cdf(pd, tstat, nu);
                        end

                    case "Left"
                        if tstat > CV % Unable to reject.
                            xline(app.UIAxes, tstat, 'LineStyle', ...
                                ':', 'Color', '#ae9ab5', ...
                                'LineWidth', 1.4);
                            tint = CV:0.001:tstat;

                            if distribution == 'F'
                                ty = pdf(pd, tint, nu1, nu2);
                            else
                                ty = pdf(pd, tint, nu);
                            end

                            tar = area(app.UIAxes, tint, ty);
                            tar.FaceAlpha = 0.04;
                        else % Able to reject.
                            xline(app.UIAxes, tstat, 'LineStyle', ...
                                ':', 'Color', '#8a22b3', ...
                                'LineWidth', 1.4);
                            if (tstat > xmin)
                                tint = xmin:0.001:tstat;

                                if distribution == 'F'
                                    ty = pdf(pd, tint, nu1, nu2);
                                else
                                    ty = pdf(pd, tint, nu);
                                end

                                tar = area(app.UIAxes, tint, ty);
                                tar.FaceAlpha = 1;
                            end
                        end
                        tar.FaceColor = '#8a22b3';
                        tar.EdgeColor = 'none';

                        if distribution == 'F'
                            pval = cdf(pd, tstat, nu1, nu2);
                        else
                            pval = cdf(pd, tstat, nu);
                        end

                    case "Both"
                        switch(distribution)
                            % Determining the p value and its area.
                            case 'F'
                                if tstat >= icdf(pd, 0.5, nu1, nu2)
                                    otherpside = 1 - cdf(pd, tstat, nu1, nu2);
                                    otherstat = icdf(pd, otherpside, nu1, nu2);

                                    pval = (1 - cdf(pd, tstat, nu1, nu2))*2;
                                else
                                    otherpside = cdf(pd, tstat, nu1, nu2);
                                    otherstat = icdf(pd, 1 - otherpside, nu1, nu2);

                                    pval = 2*cdf(pd, tstat, nu1, nu2);
                                end
                            otherwise
                                if tstat >= icdf(pd, 0.5, nu)
                                    otherpside = 1 - cdf(pd, tstat, nu);
                                    otherstat = icdf(pd, otherpside, nu);

                                    pval = (1 - cdf(pd, tstat, nu))*2;
                                else
                                    otherpside = cdf(pd, tstat, nu);
                                    otherstat = icdf(pd, 1 - otherpside, nu);

                                    pval = 2*cdf(pd, tstat, nu);
                                end
                        end

                        if (tstat > CVleft && tstat < CVright) % Unable to reject.
                            xline(app.UIAxes, tstat, 'LineStyle', ':', 'Color', ...
                                '#ae9ab5', 'LineWidth', 1.4);
                            switch(distribution)
                                case 'F'
                                    if tstat >= icdf(pd, 0.5, nu1, nu2)
                                        tintleft = CVleft:0.001:otherstat;
                                        tintright = tstat:0.001:CVright;
                                    else
                                        tintleft = CVleft:0.001:tstat;
                                        tintright = otherstat:0.001:CVright;
                                    end

                                    tyl = pdf(pd, tintleft, nu1, nu2);
                                    tyr = pdf(pd, tintright, nu1, nu2);
                                otherwise
                                    if tstat >= icdf(pd, 0.5, nu)
                                        tintleft = CVleft:0.001:otherstat;
                                        tintright = tstat:0.001:CVright;
                                    else
                                        tintleft = CVleft:0.001:tstat;
                                        tintright = otherstat:0.001:CVright;
                                    end

                                    tyl = pdf(pd, tintleft, nu);
                                    tyr = pdf(pd, tintright, nu);
                            end
                            tlar = area(app.UIAxes, tintleft, tyl);
                            tlar.FaceColor = '#8a22b3';
                            tlar.FaceAlpha = 0.04;
                            tlar.EdgeColor = 'none';

                            trar = area(app.UIAxes, tintright, tyr);
                            trar.FaceColor = '#8a22b3';
                            trar.FaceAlpha = 0.04;
                            trar.EdgeColor = 'none';
                        else % Able to reject.
                            xline(app.UIAxes, tstat, 'LineStyle', ':', 'Color', ...
                                '#8a22b3', 'LineWidth', 1.4);
                            if all([tstat > xmin tstat < xmax])
                                switch(distribution)
                                    case 'F'
                                        if tstat >= icdf(pd, 0.5, nu1, nu2)
                                            tintleft = xmin:0.001:otherstat;
                                            tintright = tstat:0.001:xmax;
                                        else
                                            tintleft = xmin:0.001:tstat;
                                            tintright = otherstat:0.001:xmax;
                                        end
                                        tyl = pdf(pd, tintleft, nu1, nu2);
                                        tyr = pdf(pd, tintright, nu1, nu2);
                                    otherwise
                                        if tstat >= icdf(pd, 0.5, nu)
                                            tintleft = xmin:0.001:otherstat;
                                            tintright = tstat:0.001:xmax;
                                        else
                                            tintleft = xmin:0.001:tstat;
                                            tintright = otherstat:0.001:xmax;
                                        end
                                        tyl = pdf(pd, tintleft, nu);
                                        tyr = pdf(pd, tintright, nu);
                                                                           
                                end
                                tlar = area(app.UIAxes, tintleft, tyl);
                                tlar.FaceColor = '#8a22b3';
                                tlar.FaceAlpha = 1;
                                tlar.EdgeColor = 'none';

                                trar = area(app.UIAxes, tintright, tyr);
                                trar.FaceColor = '#8a22b3';
                                trar.FaceAlpha = 1;
                                trar.EdgeColor = 'none';
                            end

                        end
                end

                empdec = sprintf('%%.%df', 4);
                pdec = sprintf('%%.%df', 4);
                tp = sprintf(['Test statistic = ', empdec, ...
                    ', p value = ', pdec], tstat, pval);
                subtitle(app.UIAxes, {variables, tp}, 'Interpreter', ...
                    'tex');
            else
                subtitle(app.UIAxes, {variables}, 'Interpreter', 'tex');
            end

            hold(app.UIAxes, "off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 't-distribution')
            xlabel(app.UIAxes, 't-value')
            ylabel(app.UIAxes, 'Density')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.XTick = [-5 0 5];
            app.UIAxes.XTickLabel = {'-5'; '0'; '5'};
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DenominatordfEditFieldLabel = uilabel(app.GridLayout);
            app.DenominatordfEditFieldLabel.Visible = 'off';
            app.DenominatordfEditFieldLabel.Text = 'Denominator df';
            app.DenominatordfEditField = uieditfield(app.GridLayout, 'numeric');
            app.DenominatordfEditField.LowerLimitInclusive = 'off';
            app.DenominatordfEditField.UpperLimitInclusive = 'off';
            app.DenominatordfEditField.Limits = [0 Inf];
            app.DenominatordfEditField.RoundFractionalValues = 'on';
            app.DenominatordfEditField.Visible = 'off';
            app.DenominatordfEditField.Value = 1;
            app.DegreesoffreedomEditFieldLabel = uilabel(app.GridLayout);
            app.DegreesoffreedomEditFieldLabel.Text = 'Degrees of freedom';
            app.DegreesoffreedomEditField = uieditfield(app.GridLayout, 'numeric');
            app.DegreesoffreedomEditField.LowerLimitInclusive = 'off';
            app.DegreesoffreedomEditField.UpperLimitInclusive = 'off';
            app.DegreesoffreedomEditField.Limits = [0 Inf];
            app.DegreesoffreedomEditField.RoundFractionalValues = 'on';
            app.DegreesoffreedomEditField.Value = 1;
            app.NumeratordfEditFieldLabel = uilabel(app.GridLayout);
            app.NumeratordfEditFieldLabel.Visible = 'off';
            app.NumeratordfEditFieldLabel.Text = 'Numerator df';
            app.NumeratordfEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumeratordfEditField.LowerLimitInclusive = 'off';
            app.NumeratordfEditField.UpperLimitInclusive = 'off';
            app.NumeratordfEditField.Limits = [0 Inf];
            app.NumeratordfEditField.RoundFractionalValues = 'on';
            app.NumeratordfEditField.Visible = 'off';
            app.NumeratordfEditField.Value = 1;
            app.TeststatisticEditFieldLabel = uilabel(app.GridLayout);
            app.TeststatisticEditFieldLabel.Text = 'Test statistic';
            app.TeststatisticEditField = uieditfield(app.GridLayout, 'numeric');
            app.TeststatisticEditField.LowerLimitInclusive = 'off';
            app.TeststatisticEditField.UpperLimitInclusive = 'off';
            app.TeststatisticEditField.Visible = 'off';
            app.SignificancelevelEditFieldLabel = uilabel(app.GridLayout);
            app.SignificancelevelEditFieldLabel.Text = 'Significance level';
            app.SignificancelevelEditField = uieditfield(app.GridLayout, 'numeric');
            app.SignificancelevelEditField.LowerLimitInclusive = 'off';
            app.SignificancelevelEditField.UpperLimitInclusive = 'off';
            app.SignificancelevelEditField.Limits = [0 1];
            app.SignificancelevelEditField.Value = 0.01;
            app.TailDropDownLabel = uilabel(app.GridLayout);
            app.TailDropDownLabel.Text = 'Tail';
            app.TailDropDown = uidropdown(app.GridLayout);
            app.TailDropDown.Items = {'Right', 'Left', 'Both'};
            app.TailDropDown.Value = 'Right';
            app.DistributionDropDownLabel = uilabel(app.GridLayout);
            app.DistributionDropDownLabel.Text = 'Distribution';
            app.DistributionDropDown = uidropdown(app.GridLayout);
            app.DistributionDropDown.Items = {'Student''s t', 'F', 'Chi-squared'};
            app.DistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionDropDownValueChanged, true);
            app.DistributionDropDown.Value = 'Student''s t';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HypothesisTesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end